classdef Sistem_Pentanahan_v3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Label_6                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
        InputDataPanel                  matlab.ui.container.Panel
        sLabel                          matlab.ui.control.Label
        jLabel_9                        matlab.ui.control.Label
        jLabel_8                        matlab.ui.control.Label
        jLabel_7                        matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        kVLabel_2                       matlab.ui.control.Label
        kVLabel                         matlab.ui.control.Label
        mLabel_14                       matlab.ui.control.Label
        mLabel_13                       matlab.ui.control.Label
        mLabel_12                       matlab.ui.control.Label
        mLabel_11                       matlab.ui.control.Label
        OKButtonData                    matlab.ui.control.Button
        DiameterKonduktorGridMinimumEditField  matlab.ui.control.EditField
        DiameterKonduktorGridMinimumEditFieldLabel  matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        BeratBadanLabel                 matlab.ui.control.Label
        kg70Button                      matlab.ui.control.RadioButton
        kg50Button                      matlab.ui.control.RadioButton
        FaktorPembagiArusEditField      matlab.ui.control.NumericEditField
        FaktorPembagiArusEditFieldLabel  matlab.ui.control.Label
        KetebalanPermukanTanahEditField  matlab.ui.control.NumericEditField
        KetebalanPermukanTanahEditFieldLabel  matlab.ui.control.Label
        ImpedansiTransformatorEditField_2  matlab.ui.control.NumericEditField
        ImpedansiTransformatorEditField  matlab.ui.control.NumericEditField
        ImpedansiEkuivalenSistemUrutanNolEditField_2Label_3  matlab.ui.control.Label
        ImpedansiEkuivalenSistemUrutanNolEditField_2  matlab.ui.control.NumericEditField
        ImpedansiEkuivalenSistemUrutanNolEditField  matlab.ui.control.NumericEditField
        ImpedansiEkuivalenSistemUrutanNolEditField_2Label_2  matlab.ui.control.Label
        ImpedansiEkuivalenSistemUrutanPositifEditField_2  matlab.ui.control.NumericEditField
        ImpedansiEkuivalenSistemUrutanPositifEditField  matlab.ui.control.NumericEditField
        ImpedansiEkuivalenSistemUrutanNolEditField_2Label  matlab.ui.control.Label
        TeganganSekunderTrafoEditField  matlab.ui.control.NumericEditField
        TeganganSekunderTrafoEditFieldLabel  matlab.ui.control.Label
        ResistivitasPermukaanTanahEditField  matlab.ui.control.NumericEditField
        ResistivitasPermukaanTanahLabel  matlab.ui.control.Label
        ResistivitasTanahEditField      matlab.ui.control.NumericEditField
        ResistivitasTanahEditFieldLabel  matlab.ui.control.Label
        SuhuDisekitarGridEditField      matlab.ui.control.NumericEditField
        SuhuDisekitarGridEditField_2Label  matlab.ui.control.Label
        WaktuLamanyaGangguanEditField   matlab.ui.control.NumericEditField
        WaktuLamanyaGangguanEditField_2Label  matlab.ui.control.Label
        TeganganLinetolineEditField     matlab.ui.control.NumericEditField
        TeganganLinetolineEditField_3Label  matlab.ui.control.Label
        JenisKonduktorGridDropDown      matlab.ui.control.DropDown
        JenisKonduktorGridDropDownLabel  matlab.ui.control.Label
        CLabel                          matlab.ui.control.Label
        DesainGridPanel                 matlab.ui.container.Panel
        mLabel_15                       matlab.ui.control.Label
        JarakAntarKonduktorEditFieldLabel_2  matlab.ui.control.Label
        KelilingGridEditField           matlab.ui.control.NumericEditField
        mLabel_10                       matlab.ui.control.Label
        mLabel_9                        matlab.ui.control.Label
        mLabel_8                        matlab.ui.control.Label
        mLabel_7                        matlab.ui.control.Label
        mLabel_6                        matlab.ui.control.Label
        KedalamanGridEditField          matlab.ui.control.NumericEditField
        KedalamanGridEditFieldLabel     matlab.ui.control.Label
        mLabel_5                        matlab.ui.control.Label
        m2Label                         matlab.ui.control.Label
        OKButtonDesain                  matlab.ui.control.Button
        DiameterKonduktorGridEditField  matlab.ui.control.NumericEditField
        DiameterKonduktorGridEditField_2Label  matlab.ui.control.Label
        LuasTanahyangTersediaEditField  matlab.ui.control.NumericEditField
        LuasTanahyangTersediaEditFieldLabel  matlab.ui.control.Label
        BentukDropDown                  matlab.ui.control.DropDown
        BentukDropDownLabel             matlab.ui.control.Label
        PanjangGroundRodEditField       matlab.ui.control.NumericEditField
        PanjangGroundRodEditFieldLabel  matlab.ui.control.Label
        JumlahGroundRodEditField        matlab.ui.control.NumericEditField
        JumlahGroundRodEditFieldLabel   matlab.ui.control.Label
        JarakAntarKonduktorEditField    matlab.ui.control.NumericEditField
        JarakAntarKonduktorEditFieldLabel  matlab.ui.control.Label
        PanjangMaksKonduktorArahYEditField  matlab.ui.control.NumericEditField
        PanjangMaksKonduktorArahYEditFieldLabel  matlab.ui.control.Label
        PanjangMaksKonduktorArahXEditField  matlab.ui.control.NumericEditField
        PanjangMaksKonduktorArahXEditFieldLabel  matlab.ui.control.Label
        HasilPanel                      matlab.ui.container.Panel
        VLabel_5                        matlab.ui.control.Label
        VLabel_4                        matlab.ui.control.Label
        VLabel_3                        matlab.ui.control.Label
        VLabel_2                        matlab.ui.control.Label
        VLabel                          matlab.ui.control.Label
        ALabel_4                        matlab.ui.control.Label
        ALabel_3                        matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        ALabel_2                        matlab.ui.control.Label
        ALabel                          matlab.ui.control.Label
        ArusFibrilasiEditField          matlab.ui.control.EditField
        ArusFibrilasiEditFieldLabel     matlab.ui.control.Label
        TeganganSentuhToleransiEditField  matlab.ui.control.EditField
        TeganganSentuhToleransiEditFieldLabel  matlab.ui.control.Label
        TeganganSentuhSebenarnyaEditField  matlab.ui.control.EditField
        TeganganSentuhSebenarnyaEditFieldLabel  matlab.ui.control.Label
        TeganganLangkahToleransiEditField  matlab.ui.control.EditField
        TeganganLangkahToleransiEditFieldLabel  matlab.ui.control.Label
        TeganganLangkahSebenarnyaEditField  matlab.ui.control.EditField
        TeganganLangkahSebenarnyaEditFieldLabel  matlab.ui.control.Label
        GPREditField                    matlab.ui.control.EditField
        ArusGangguanBagianTrafoEditField_3Label_2  matlab.ui.control.Label
        ArusGridEditField               matlab.ui.control.EditField
        ArusGridEditField_2Label        matlab.ui.control.Label
        ResistansiGridEditField         matlab.ui.control.EditField
        ResistansiGridEditField_2Label  matlab.ui.control.Label
        ArusGangguanBagianTrafoEditField  matlab.ui.control.EditField
        ArusGangguanBagianTrafoEditFieldLabel  matlab.ui.control.Label
        ArusGangguanBagianSistemEditField  matlab.ui.control.EditField
        ArusGangguanBagianSistemEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        tf % Lamanya gangguan 
        rho % Resistivitas Tanah
        rho_s % Resistivitas Permukaan Tanah
        hs % Ketebalan PermukaanTanah
        Sf % Faktor Pembagi Arus
        If_sistem % Arus Gangguan Sistem
        Em % Tegangan Sentuh Sebenarnya
        Es % Tegangan Langkah Sebenarnya
        Etouch % Tegangan Sentuh yang Diizinkan
        Estep % Tegangan Langkah yang Diizinkan
    end
    
    methods (Access = private)
        
        function If_sistem = ArusGangguanSistem(~, R0_sistem, X0_sistem, R1_sistem, X1_sistem, R2_sistem, X2_sistem, v_LinetoLine)

            E = v_LinetoLine/sqrt(3);
            If_sistem = (3*E)/((R1_sistem+R2_sistem+R0_sistem)+(1j*(X1_sistem+X2_sistem+X0_sistem)));
            If_sistem = abs(If_sistem);
            
        end
        
        function If_trafo = ArusGangguanTrafo(~, R0_trafo, X0_trafo, R1_trafo, X1_trafo, R1_sistem, X1_sistem, v_SekunderTrafo, v_LinetoLine)
            
            Z1_sistem = complex(R1_sistem, X1_sistem);
            Z1_trafo_old = complex(R1_trafo,X1_trafo);
            Z1_trafo_new = ((((v_SekunderTrafo/1000)/(v_LinetoLine/1000))^2)*Z1_sistem)+Z1_trafo_old;
            
            R0 = R0_trafo;
            X0 = X0_trafo;
            R1 = real(Z1_trafo_new);
            X1 = imag(Z1_trafo_new);
            R2 = R1;
            X2 = X1;
            
            E = (v_SekunderTrafo)/sqrt(3);
            If_trafo = (3*E)/((R1+R2+R0)+(1j*(X1+X2+X0)));
            If_trafo = abs(If_trafo);
            
        end
            
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.OKButtonDesain.Enable = 'off'; 
            app.ResetButton.Enable = 'off';
            app.BentukDropDown.Enable = 'off';
            app.LuasTanahyangTersediaEditField.Enable = 'off';
            app.DiameterKonduktorGridEditField.Enable = 'off';
            app.PanjangMaksKonduktorArahXEditField.Enable = 'off';
            app.PanjangMaksKonduktorArahYEditField.Enable = 'off';
            app.JarakAntarKonduktorEditField.Enable = 'off';
            app.JumlahGroundRodEditField.Enable = 'off';
            app.PanjangGroundRodEditField.Enable = 'off';
            app.KedalamanGridEditField.Enable = 'off';
            app.ResetButton.Enable = 'off';
            app.KelilingGridEditField.Enable = 'off';
        end

        % Value changed function: TeganganLinetolineEditField
        function TeganganLinetolineEditFieldValueChanged(app, event)

        end

        % Button pushed function: OKButtonData
        function OKButtonDataPushed(app, event)
            % Mencari Arus Gangguan Sistem
            R0_sistem = app.ImpedansiEkuivalenSistemUrutanNolEditField.Value;
            X0_sistem = app.ImpedansiEkuivalenSistemUrutanNolEditField_2.Value;
            R1_sistem = app.ImpedansiEkuivalenSistemUrutanPositifEditField.Value;
            X1_sistem = app.ImpedansiEkuivalenSistemUrutanPositifEditField_2.Value;
            R2_sistem = R1_sistem;
            X2_sistem = X1_sistem;
            v_LinetoLine = app.TeganganLinetolineEditField.Value*1000;
            app.If_sistem = ArusGangguanSistem(app, R0_sistem, X0_sistem, R1_sistem, X1_sistem, R2_sistem, X2_sistem, v_LinetoLine);
            app.ArusGangguanBagianSistemEditField.Value = string(app.If_sistem);
            
            % Mencari Arus Gangguan Trafo
            R0_trafo = app.ImpedansiTransformatorEditField.Value;
            R1_trafo = R0_trafo; 
            X0_trafo = app.ImpedansiTransformatorEditField_2.Value;
            X1_trafo = X0_trafo;
            v_SekunderTrafo = app.TeganganSekunderTrafoEditField.Value*1000;
            If_trafo = ArusGangguanTrafo(app, R0_trafo, X0_trafo, R1_trafo, X1_trafo, R1_sistem, X1_sistem, v_SekunderTrafo, v_LinetoLine);
            app.ArusGangguanBagianTrafoEditField.Value = string(If_trafo);
            
            % Mencari Diameter Konduktor Grid Minimum
            switch app.JenisKonduktorGridDropDown.Value
                case 'Copper, annealed soft-drawn'
                    alpha_r = 0.00393;
                    k0 = 234;
                    Tm = 1083;
                    rho_r = 1.72;
                    tcap = 3.42;
                case 'Copper, commercial hard-drawn'
                    alpha_r = 0.00381;
                    k0 = 242;
                    Tm = 1084;
                    rho_r = 1.78;
                    tcap = 3.42;
                case 'Copper-clad steel wire 40% Conductivity' 
                    alpha_r = 0.00378;
                    k0 = 245;
                    Tm = 1084;
                    rho_r = 5.86;
                    tcap = 3.85;
                case 'Copper-clad steel wire 30% Conductivity'
                    alpha_r = 0.00378;
                    k0 = 245;
                    Tm = 1084;
                    rho_r = 4.4;
                    tcap = 3.85;
            end
            If_for_diameter = max([app.If_sistem, If_trafo]);
            app.tf = app.WaktuLamanyaGangguanEditField.Value;
            Ta = app.SuhuDisekitarGridEditField.Value;
            Akcmil = ((If_for_diameter/1000)*197.4)/(sqrt((tcap/(app.tf*alpha_r*rho_r))*log((k0+Tm)/(k0+Ta))));
            Amm2 = Akcmil/1.974;
            Am2 = Amm2/1000000;
            dmin = sqrt((Am2*4)/pi);
            
            app.DiameterKonduktorGridMinimumEditField.Value = string(dmin);
            
            % data lain
            app.rho = app.ResistivitasTanahEditField.Value;
            app.rho_s = app.ResistivitasPermukaanTanahEditField.Value;
            app.hs = app.KetebalanPermukanTanahEditField.Value;
            app.Sf = app.FaktorPembagiArusEditField.Value;
            
            % Mencari Arus Fibrilasi
            Ik_50 = 0.116/sqrt(app.tf);
            Ik_70 = 0.157/sqrt(app.tf);
            Cs = 1-((0.09*(1-(app.rho/app.rho_s)))/((2*app.hs)+0.09));
            
            % Mencari Tegangan Sentuh dan Langkah Toleransi
            if app.kg50Button.Value == 1
                app.ArusFibrilasiEditField.Value = string(Ik_50);
                Etouch50 = (1000+(1.5*Cs*app.rho_s))*Ik_50;
                app.Etouch = Etouch50;
                app.TeganganSentuhToleransiEditField.Value = string(Etouch50);
                Estep50 = (1000+(6*Cs*app.rho_s))*Ik_50;
                app.Estep = Estep50;
                app.TeganganLangkahToleransiEditField.Value = string(Estep50);
            elseif app.kg70Button.Value == 1
                app.ArusFibrilasiEditField.Value = string(Ik_70);
                Etouch70 = (1000+(1.5*Cs*app.rho_s))*Ik_70;
                app.Etouch = Etouch70;
                app.TeganganSentuhToleransiEditField.Value = string(Etouch70);
                Estep70 = (1000+(6*Cs*app.rho_s))*Ik_70;
                app.Estep = Estep70;
                app.TeganganLangkahToleransiEditField.Value = string(Estep70);
            end
            
            app.OKButtonDesain.Enable = 'on';
            app.ResetButton.Enable = 'on';
            app.BentukDropDown.Enable = 'on';
            app.LuasTanahyangTersediaEditField.Enable = 'on';
            app.DiameterKonduktorGridEditField.Enable = 'on';
            app.PanjangMaksKonduktorArahXEditField.Enable = 'on';
            app.PanjangMaksKonduktorArahYEditField.Enable = 'on';
            app.JarakAntarKonduktorEditField.Enable = 'on';
            app.JumlahGroundRodEditField.Enable = 'on';
            app.PanjangGroundRodEditField.Enable = 'on';
            app.KedalamanGridEditField.Enable = 'on';
            app.ResetButton.Enable = 'on';
        end

        % Value changed function: WaktuLamanyaGangguanEditField
        function WaktuLamanyaGangguanEditFieldValueChanged(app, event)

        end

        % Value changed function: JenisKonduktorGridDropDown
        function JenisKonduktorGridDropDownValueChanged(app, event)

        end

        % Button pushed function: OKButtonDesain
        function OKButtonDesainPushed(app, event)
         % Mendesain grid
            A = app.LuasTanahyangTersediaEditField.Value;
            d = app.DiameterKonduktorGridEditField.Value;
            Lx = app.PanjangMaksKonduktorArahXEditField.Value;
            Ly = app.PanjangMaksKonduktorArahYEditField.Value;
            D = app.JarakAntarKonduktorEditField.Value;
            Nr = app.JumlahGroundRodEditField.Value;
            Lr = app.PanjangGroundRodEditField.Value;
            h = app.KedalamanGridEditField.Value;

            LC = (Ly*((Lx/D)+1)) + (Lx*((Ly/D)+1));
            LR = Nr*Lr;
            LT = LC+LR;
            
            % Menghitung Resistansi Grid
            Rg = app.rho*((1/LT)+(1/sqrt(20*A)*(1+(1/(1+(h*sqrt(20/A)))))));
            app.ResistansiGridEditField.Value = string(Rg);
            
            % Mencari Arus Grid
            Ig = app.Sf*app.If_sistem;
            app.ArusGridEditField.Value = string(Ig);
            
            % Mencari GPR
            GPR = Ig*Rg;
            app.GPREditField.Value = string(GPR);
            
            if GPR > app.Etouch
                 % Mencari Tegangan Mesh/Sentuh Sebenarnya
                 switch app.BentukDropDown.Value
                    case 'Persegi'
                        LP = (2*Lx)+(2*Ly);
                        nb = 1;
                        nc = 1;
                        nd = 1;
                    case 'Persegi Panjang'
                        LP = (2*Lx)+(2*Ly);
                        nb = sqrt(LP/(4*sqrt(A)));
                        nc = 1;
                        nd = 1;
                    case 'Lainnya'
                        LP = app.KelilingGridEditField.Value; 
                        nb = sqrt(LP/(4*sqrt(A)));
                        nc = ((Lx*Ly)/A)^((0.7*A)/(Lx*Ly));
                        nd = 1;
                 end
            
                 na = (2*LC)/LP;
                 n = na*nb*nc*nd;
                 Ki = 0.644+(0.148*n);
                 Kii = 1;
                 Kh = sqrt(1+(h/1));
                 Km = 1/(2*pi)*(log((D^2)/(16*h*d)+((D+2*h)^2)/(8*D*d)-h/(4*d))+(Kii/Kh)*log(8/(pi*(2*n-1))));
                 app.Em = (app.rho*Ig*Km*Ki) /((LC+((1.55+1.22*(Lr/(sqrt(((Lx)^2)+(Ly)^2))))*LR)));
            
                 % Mencari Tegangan Langkah Sebenarnya
                 Ks = (1/pi)*((1/(2*h))+ (1/(D+h)) + ((1/D)*(1-(0.5)^(n-2))));
                 app.Es = (app.rho*Ig*Ks*Ki)/((0.75*LC)+(0.85*LR));
            
                 app.TeganganSentuhSebenarnyaEditField.Value = string(app.Em);
                 app.TeganganLangkahSebenarnyaEditField.Value = string(app.Es);
             
                 if app.Em < app.Etouch
                    if app.Es < app.Estep
                        app.TextArea.Value = "Desain Pentanahan Aman";
                        app.Label_5.Text = 'Tegangan Sentuh & Tegangan Langkah Lebih Kecil dari Tegangan Toleransi';
                        app.Label_6.Text = 'Syarat Keamanan Terpenuhi';   
                    else
                        app.TextArea.Value = "Desain Pentanahan Tidak Aman";
                        app.Label_5.Text = 'Tegangan Langkah Lebih Besar dari Tegangan Toleransi';
                        app.Label_6.Text = 'Perlu Didesain Ulang';
                    end
                 elseif app.Es < app.Estep
                    app.TextArea.Value = "Desain Pentanahan Tidak Aman";
                    app.Label_5.Text = 'Tegangan Sentuh Lebih Besar dari Tegangan Toleransi';
                    app.Label_6.Text = 'Perlu Didesain Ulang';
                 else
                    app.TextArea.Value = "Desain Pentanahan Tidak Aman";
                    app.Label_5.Text = 'Tegangan Sentuh & Tegangan Langkah Lebih Besar dari Tegangan Toleransi';
                    app.Label_6.Text = 'Perlu Didesain Ulang';
                 end
            elseif GPR < app.Etouch
                app.TextArea.Value = "Desain Pentanahan Aman";
                app.Label_5.Text = 'GPR Lebih Kecil dari Tegangan Sentuh Toleransi';
                app.Label_6.Text = 'Tidak Perlu Menghitung Lebih Lanjut';
            end
               
            
        end

        % Value changed function: KedalamanGridEditField
        function KedalamanGridEditFieldValueChanged(app, event)

        end

        % Callback function
        function UIFigureCloseRequest(app, event)

        end

        % Callback function
        function DesainUlangButtonPushed(app, event)

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.OKButtonData.Enable = 'on';
            app.OKButtonDesain.Enable = 'off';
            app.DiameterKonduktorGridMinimumEditField.Value = '';
            app.ArusGangguanBagianSistemEditField.Value = '';
            app.ArusGangguanBagianTrafoEditField.Value = '';
            app.ResistansiGridEditField.Value = '';
            app.ArusGridEditField.Value = '';
            app.ArusFibrilasiEditField.Value = '';
            app.GPREditField.Value = '';
            app.TeganganSentuhToleransiEditField.Value = '';
            app.TeganganSentuhSebenarnyaEditField.Value = '';
            app.TeganganLangkahToleransiEditField.Value = '';
            app.TeganganLangkahSebenarnyaEditField.Value = '';
            app.TextArea.Value = '';
            app.ResetButton.Enable = 'off';
            app.BentukDropDown.Enable = 'off';
            app.LuasTanahyangTersediaEditField.Enable = 'off';
            app.DiameterKonduktorGridEditField.Enable = 'off';
            app.PanjangMaksKonduktorArahXEditField.Enable = 'off';
            app.PanjangMaksKonduktorArahYEditField.Enable = 'off';
            app.JarakAntarKonduktorEditField.Enable = 'off';
            app.JumlahGroundRodEditField.Enable = 'off';
            app.PanjangGroundRodEditField.Enable = 'off';
            app.KedalamanGridEditField.Enable = 'off';
            app.LuasTanahyangTersediaEditField.Value = 0;
            app.DiameterKonduktorGridEditField.Value = 0;
            app.PanjangMaksKonduktorArahXEditField.Value = 0;
            app.PanjangMaksKonduktorArahYEditField.Value = 0;
            app.JarakAntarKonduktorEditField.Value = 0;
            app.JumlahGroundRodEditField.Value = 0;
            app.PanjangGroundRodEditField.Value = 0;
            app.KedalamanGridEditField.Value = 0.25;
            app.ImpedansiEkuivalenSistemUrutanPositifEditField.Value = 0;
            app.ImpedansiEkuivalenSistemUrutanPositifEditField_2.Value = 0;
            app.ImpedansiEkuivalenSistemUrutanNolEditField.Value = 0;
            app.ImpedansiEkuivalenSistemUrutanNolEditField_2.Value = 0;
            app.ImpedansiTransformatorEditField.Value = 0;
            app.ImpedansiTransformatorEditField_2.Value = 0;
            app.TeganganLinetolineEditField.Value = 0;
            app.TeganganSekunderTrafoEditField.Value = 0;
            app.WaktuLamanyaGangguanEditField.Value = 0;
            app.FaktorPembagiArusEditField.Value = 0;
            app.SuhuDisekitarGridEditField.Value = 0;
            app.ResistivitasTanahEditField.Value = 0;
            app.ResistivitasPermukaanTanahEditField.Value = 0;
            app.KetebalanPermukanTanahEditField.Value = 0;
            app.DiameterKonduktorGridMinimumEditField.Value = '';
            app.KelilingGridEditField.Value = 0;
        end

        % Value changed function: TeganganSentuhToleransiEditField
        function TeganganSentuhToleransiEditFieldValueChanged(app, event)

        end

        % Value changed function: BentukDropDown
        function BentukDropDownValueChanged(app, event)
             switch app.BentukDropDown.Value
                 case 'Persegi'
                     app.KelilingGridEditField.Enable = 'off';
                 case 'Persegi Panjang'
                     app.KelilingGridEditField.Enable = 'off';
                 case 'Lainnya'
                     app.KelilingGridEditField.Enable = 'on';
             end
        end

        % Drop down opening function: BentukDropDown
        function BentukDropDownOpening(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HasilPanel = uipanel(app.UIFigure);
            app.HasilPanel.Title = 'Hasil';
            app.ArusGangguanBagianSistemEditFieldLabel = uilabel(app.HasilPanel);
            app.ArusGangguanBagianSistemEditFieldLabel.Text = 'Arus Gangguan Bagian Sistem';
            app.ArusGangguanBagianSistemEditField = uieditfield(app.HasilPanel, 'text');
            app.ArusGangguanBagianSistemEditField.Editable = 'off';
            app.ArusGangguanBagianTrafoEditFieldLabel = uilabel(app.HasilPanel);
            app.ArusGangguanBagianTrafoEditFieldLabel.Text = 'Arus Gangguan Bagian Trafo';
            app.ArusGangguanBagianTrafoEditField = uieditfield(app.HasilPanel, 'text');
            app.ArusGangguanBagianTrafoEditField.Editable = 'off';
            app.ResistansiGridEditField_2Label = uilabel(app.HasilPanel);
            app.ResistansiGridEditField_2Label.Text = 'Resistansi Grid';
            app.ResistansiGridEditField = uieditfield(app.HasilPanel, 'text');
            app.ResistansiGridEditField.Editable = 'off';
            app.ArusGridEditField_2Label = uilabel(app.HasilPanel);
            app.ArusGridEditField_2Label.Text = 'Arus Grid ';
            app.ArusGridEditField = uieditfield(app.HasilPanel, 'text');
            app.ArusGridEditField.Editable = 'off';
            app.ArusGangguanBagianTrafoEditField_3Label_2 = uilabel(app.HasilPanel);
            app.ArusGangguanBagianTrafoEditField_3Label_2.Text = 'GPR';
            app.GPREditField = uieditfield(app.HasilPanel, 'text');
            app.GPREditField.Editable = 'off';
            app.TeganganLangkahSebenarnyaEditFieldLabel = uilabel(app.HasilPanel);
            app.TeganganLangkahSebenarnyaEditFieldLabel.Text = 'Tegangan Langkah Sebenarnya';
            app.TeganganLangkahSebenarnyaEditField = uieditfield(app.HasilPanel, 'text');
            app.TeganganLangkahSebenarnyaEditField.Editable = 'off';
            app.TeganganLangkahToleransiEditFieldLabel = uilabel(app.HasilPanel);
            app.TeganganLangkahToleransiEditFieldLabel.Text = 'Tegangan Langkah Toleransi';
            app.TeganganLangkahToleransiEditField = uieditfield(app.HasilPanel, 'text');
            app.TeganganLangkahToleransiEditField.Editable = 'off';
            app.TeganganSentuhSebenarnyaEditFieldLabel = uilabel(app.HasilPanel);
            app.TeganganSentuhSebenarnyaEditFieldLabel.Text = 'Tegangan Sentuh Sebenarnya';
            app.TeganganSentuhSebenarnyaEditField = uieditfield(app.HasilPanel, 'text');
            app.TeganganSentuhSebenarnyaEditField.Editable = 'off';
            app.TeganganSentuhToleransiEditFieldLabel = uilabel(app.HasilPanel);
            app.TeganganSentuhToleransiEditFieldLabel.Text = 'Tegangan Sentuh Toleransi';
            app.TeganganSentuhToleransiEditField = uieditfield(app.HasilPanel, 'text');
            app.TeganganSentuhToleransiEditField.ValueChangedFcn = createCallbackFcn(app, @TeganganSentuhToleransiEditFieldValueChanged, true);
            app.TeganganSentuhToleransiEditField.Editable = 'off';
            app.ArusFibrilasiEditFieldLabel = uilabel(app.HasilPanel);
            app.ArusFibrilasiEditFieldLabel.Text = 'Arus Fibrilasi';
            app.ArusFibrilasiEditField = uieditfield(app.HasilPanel, 'text');
            app.ArusFibrilasiEditField.Editable = 'off';
            app.ALabel = uilabel(app.HasilPanel);
            app.ALabel.Interpreter = 'latex';
            app.ALabel.Text = 'A';
            app.ALabel_2 = uilabel(app.HasilPanel);
            app.ALabel_2.Interpreter = 'latex';
            app.ALabel_2.Text = 'A';
            app.Label_4 = uilabel(app.HasilPanel);
            app.Label_4.Interpreter = 'latex';
            app.Label_4.Text = '$Ω$';
            app.ALabel_3 = uilabel(app.HasilPanel);
            app.ALabel_3.Interpreter = 'latex';
            app.ALabel_3.Text = 'A';
            app.ALabel_4 = uilabel(app.HasilPanel);
            app.ALabel_4.Interpreter = 'latex';
            app.ALabel_4.Text = 'A';
            app.VLabel = uilabel(app.HasilPanel);
            app.VLabel.Interpreter = 'latex';
            app.VLabel.Text = 'V';
            app.VLabel_2 = uilabel(app.HasilPanel);
            app.VLabel_2.Interpreter = 'latex';
            app.VLabel_2.Text = 'V';
            app.VLabel_3 = uilabel(app.HasilPanel);
            app.VLabel_3.Interpreter = 'latex';
            app.VLabel_3.Text = 'V';
            app.VLabel_4 = uilabel(app.HasilPanel);
            app.VLabel_4.Interpreter = 'latex';
            app.VLabel_4.Text = 'V';
            app.VLabel_5 = uilabel(app.HasilPanel);
            app.VLabel_5.Interpreter = 'latex';
            app.VLabel_5.Text = 'V';
            app.DesainGridPanel = uipanel(app.UIFigure);
            app.DesainGridPanel.Title = 'Desain Grid';
            app.PanjangMaksKonduktorArahXEditFieldLabel = uilabel(app.DesainGridPanel);
            app.PanjangMaksKonduktorArahXEditFieldLabel.Text = 'Panjang Maks Konduktor Arah X';
            app.PanjangMaksKonduktorArahXEditField = uieditfield(app.DesainGridPanel, 'numeric');
            app.PanjangMaksKonduktorArahYEditFieldLabel = uilabel(app.DesainGridPanel);
            app.PanjangMaksKonduktorArahYEditFieldLabel.Text = 'Panjang Maks Konduktor Arah Y';
            app.PanjangMaksKonduktorArahYEditField = uieditfield(app.DesainGridPanel, 'numeric');
            app.JarakAntarKonduktorEditFieldLabel = uilabel(app.DesainGridPanel);
            app.JarakAntarKonduktorEditFieldLabel.Text = 'Jarak Antar Konduktor';
            app.JarakAntarKonduktorEditField = uieditfield(app.DesainGridPanel, 'numeric');
            app.JumlahGroundRodEditFieldLabel = uilabel(app.DesainGridPanel);
            app.JumlahGroundRodEditFieldLabel.Text = 'Jumlah Ground Rod';
            app.JumlahGroundRodEditField = uieditfield(app.DesainGridPanel, 'numeric');
            app.PanjangGroundRodEditFieldLabel = uilabel(app.DesainGridPanel);
            app.PanjangGroundRodEditFieldLabel.Text = 'Panjang Ground Rod';
            app.PanjangGroundRodEditField = uieditfield(app.DesainGridPanel, 'numeric');
            app.BentukDropDownLabel = uilabel(app.DesainGridPanel);
            app.BentukDropDownLabel.Text = 'Bentuk ';
            app.BentukDropDown = uidropdown(app.DesainGridPanel);
            app.BentukDropDown.Items = {'Persegi', 'Persegi Panjang', 'Lainnya'};
            app.BentukDropDown.DropDownOpeningFcn = createCallbackFcn(app, @BentukDropDownOpening, true);
            app.BentukDropDown.ValueChangedFcn = createCallbackFcn(app, @BentukDropDownValueChanged, true);
            app.BentukDropDown.Value = 'Persegi';
            app.LuasTanahyangTersediaEditFieldLabel = uilabel(app.DesainGridPanel);
            app.LuasTanahyangTersediaEditFieldLabel.Text = 'Luas Tanah yang Tersedia ';
            app.LuasTanahyangTersediaEditField = uieditfield(app.DesainGridPanel, 'numeric');
            app.DiameterKonduktorGridEditField_2Label = uilabel(app.DesainGridPanel);
            app.DiameterKonduktorGridEditField_2Label.Text = 'Diameter Konduktor Grid';
            app.DiameterKonduktorGridEditField = uieditfield(app.DesainGridPanel, 'numeric');
            app.OKButtonDesain = uibutton(app.DesainGridPanel, 'push');
            app.OKButtonDesain.ButtonPushedFcn = createCallbackFcn(app, @OKButtonDesainPushed, true);
            app.OKButtonDesain.Text = 'OK';
            app.m2Label = uilabel(app.DesainGridPanel);
            app.m2Label.Interpreter = 'latex';
            app.m2Label.Text = '$ m^{2} $';
            app.mLabel_5 = uilabel(app.DesainGridPanel);
            app.mLabel_5.Interpreter = 'latex';
            app.mLabel_5.Text = '$ m $';
            app.KedalamanGridEditFieldLabel = uilabel(app.DesainGridPanel);
            app.KedalamanGridEditFieldLabel.Text = 'Kedalaman Grid';
            app.KedalamanGridEditField = uieditfield(app.DesainGridPanel, 'numeric');
            app.KedalamanGridEditField.Limits = [0.25 2.5];
            app.KedalamanGridEditField.ValueChangedFcn = createCallbackFcn(app, @KedalamanGridEditFieldValueChanged, true);
            app.KedalamanGridEditField.Value = 0.25;
            app.mLabel_6 = uilabel(app.DesainGridPanel);
            app.mLabel_6.Interpreter = 'latex';
            app.mLabel_6.Text = '$ m $';
            app.mLabel_7 = uilabel(app.DesainGridPanel);
            app.mLabel_7.Interpreter = 'latex';
            app.mLabel_7.Text = '$ m $';
            app.mLabel_8 = uilabel(app.DesainGridPanel);
            app.mLabel_8.Interpreter = 'latex';
            app.mLabel_8.Text = '$ m $';
            app.mLabel_9 = uilabel(app.DesainGridPanel);
            app.mLabel_9.Interpreter = 'latex';
            app.mLabel_9.Text = '$ m $';
            app.mLabel_10 = uilabel(app.DesainGridPanel);
            app.mLabel_10.Interpreter = 'latex';
            app.mLabel_10.Text = '$ m $';
            app.KelilingGridEditField = uieditfield(app.DesainGridPanel, 'numeric');
            app.JarakAntarKonduktorEditFieldLabel_2 = uilabel(app.DesainGridPanel);
            app.JarakAntarKonduktorEditFieldLabel_2.Text = 'Keliling Grid';
            app.mLabel_15 = uilabel(app.DesainGridPanel);
            app.mLabel_15.Interpreter = 'latex';
            app.mLabel_15.Text = '$ m $';
            app.InputDataPanel = uipanel(app.UIFigure);
            app.InputDataPanel.Title = 'Input Data';
            app.CLabel = uilabel(app.InputDataPanel);
            app.CLabel.Interpreter = 'latex';
            app.CLabel.Text = '°C';
            app.JenisKonduktorGridDropDownLabel = uilabel(app.InputDataPanel);
            app.JenisKonduktorGridDropDownLabel.Text = 'Jenis Konduktor Grid';
            app.JenisKonduktorGridDropDown = uidropdown(app.InputDataPanel);
            app.JenisKonduktorGridDropDown.Items = {'Copper, annealed soft-drawn', 'Copper, commercial hard-drawn', 'Copper-clad steel wire 40% Conductivity', 'Copper-clad steel wire 30% Conductivity'};
            app.JenisKonduktorGridDropDown.ValueChangedFcn = createCallbackFcn(app, @JenisKonduktorGridDropDownValueChanged, true);
            app.JenisKonduktorGridDropDown.Value = 'Copper, annealed soft-drawn';
            app.TeganganLinetolineEditField_3Label = uilabel(app.InputDataPanel);
            app.TeganganLinetolineEditField_3Label.Text = 'Tegangan Line-to-line';
            app.TeganganLinetolineEditField = uieditfield(app.InputDataPanel, 'numeric');
            app.TeganganLinetolineEditField.ValueDisplayFormat = '%.0f';
            app.TeganganLinetolineEditField.ValueChangedFcn = createCallbackFcn(app, @TeganganLinetolineEditFieldValueChanged, true);
            app.WaktuLamanyaGangguanEditField_2Label = uilabel(app.InputDataPanel);
            app.WaktuLamanyaGangguanEditField_2Label.Text = 'Waktu Lamanya Gangguan';
            app.WaktuLamanyaGangguanEditField = uieditfield(app.InputDataPanel, 'numeric');
            app.WaktuLamanyaGangguanEditField.ValueChangedFcn = createCallbackFcn(app, @WaktuLamanyaGangguanEditFieldValueChanged, true);
            app.SuhuDisekitarGridEditField_2Label = uilabel(app.InputDataPanel);
            app.SuhuDisekitarGridEditField_2Label.Text = 'Suhu Disekitar Grid';
            app.SuhuDisekitarGridEditField = uieditfield(app.InputDataPanel, 'numeric');
            app.ResistivitasTanahEditFieldLabel = uilabel(app.InputDataPanel);
            app.ResistivitasTanahEditFieldLabel.Text = 'Resistivitas Tanah';
            app.ResistivitasTanahEditField = uieditfield(app.InputDataPanel, 'numeric');
            app.ResistivitasPermukaanTanahLabel = uilabel(app.InputDataPanel);
            app.ResistivitasPermukaanTanahLabel.Text = 'Resistivitas Permukaan Tanah';
            app.ResistivitasPermukaanTanahEditField = uieditfield(app.InputDataPanel, 'numeric');
            app.TeganganSekunderTrafoEditFieldLabel = uilabel(app.InputDataPanel);
            app.TeganganSekunderTrafoEditFieldLabel.Text = 'Tegangan Sekunder Trafo';
            app.TeganganSekunderTrafoEditField = uieditfield(app.InputDataPanel, 'numeric');
            app.TeganganSekunderTrafoEditField.ValueDisplayFormat = '%.0f';
            app.ImpedansiEkuivalenSistemUrutanNolEditField_2Label = uilabel(app.InputDataPanel);
            app.ImpedansiEkuivalenSistemUrutanNolEditField_2Label.Text = 'Impedansi Ekuivalen Sistem Urutan Positif';
            app.ImpedansiEkuivalenSistemUrutanPositifEditField = uieditfield(app.InputDataPanel, 'numeric');
            app.ImpedansiEkuivalenSistemUrutanPositifEditField_2 = uieditfield(app.InputDataPanel, 'numeric');
            app.ImpedansiEkuivalenSistemUrutanNolEditField_2Label_2 = uilabel(app.InputDataPanel);
            app.ImpedansiEkuivalenSistemUrutanNolEditField_2Label_2.Text = 'Impedansi Ekuivalen Sistem Urutan Nol';
            app.ImpedansiEkuivalenSistemUrutanNolEditField = uieditfield(app.InputDataPanel, 'numeric');
            app.ImpedansiEkuivalenSistemUrutanNolEditField_2 = uieditfield(app.InputDataPanel, 'numeric');
            app.ImpedansiEkuivalenSistemUrutanNolEditField_2Label_3 = uilabel(app.InputDataPanel);
            app.ImpedansiEkuivalenSistemUrutanNolEditField_2Label_3.Text = 'Impedansi Transformator';
            app.ImpedansiTransformatorEditField = uieditfield(app.InputDataPanel, 'numeric');
            app.ImpedansiTransformatorEditField_2 = uieditfield(app.InputDataPanel, 'numeric');
            app.KetebalanPermukanTanahEditFieldLabel = uilabel(app.InputDataPanel);
            app.KetebalanPermukanTanahEditFieldLabel.Text = 'Ketebalan Permukan Tanah';
            app.KetebalanPermukanTanahEditField = uieditfield(app.InputDataPanel, 'numeric');
            app.FaktorPembagiArusEditFieldLabel = uilabel(app.InputDataPanel);
            app.FaktorPembagiArusEditFieldLabel.Text = 'Faktor Pembagi Arus';
            app.FaktorPembagiArusEditField = uieditfield(app.InputDataPanel, 'numeric');
            app.ButtonGroup = uibuttongroup(app.InputDataPanel);
            app.kg50Button = uiradiobutton(app.ButtonGroup);
            app.kg50Button.Text = '50 kg';
            app.kg50Button.Value = true;
            app.kg70Button = uiradiobutton(app.ButtonGroup);
            app.kg70Button.Text = '70 kg';
            app.BeratBadanLabel = uilabel(app.ButtonGroup);
            app.BeratBadanLabel.Text = 'Berat Badan';
            app.DiameterKonduktorGridMinimumEditFieldLabel = uilabel(app.InputDataPanel);
            app.DiameterKonduktorGridMinimumEditFieldLabel.Text = 'Diameter Konduktor Grid Minimum';
            app.DiameterKonduktorGridMinimumEditField = uieditfield(app.InputDataPanel, 'text');
            app.DiameterKonduktorGridMinimumEditField.Editable = 'off';
            app.OKButtonData = uibutton(app.InputDataPanel, 'push');
            app.OKButtonData.ButtonPushedFcn = createCallbackFcn(app, @OKButtonDataPushed, true);
            app.OKButtonData.Text = 'OK';
            app.mLabel_11 = uilabel(app.InputDataPanel);
            app.mLabel_11.Interpreter = 'latex';
            app.mLabel_11.Text = '$ m $';
            app.mLabel_12 = uilabel(app.InputDataPanel);
            app.mLabel_12.Interpreter = 'latex';
            app.mLabel_12.Text = '$ m $';
            app.mLabel_13 = uilabel(app.InputDataPanel);
            app.mLabel_13.Interpreter = 'latex';
            app.mLabel_13.Text = '$ Ω.m $';
            app.mLabel_14 = uilabel(app.InputDataPanel);
            app.mLabel_14.Interpreter = 'latex';
            app.mLabel_14.Text = '$ Ω.m $';
            app.kVLabel = uilabel(app.InputDataPanel);
            app.kVLabel.Interpreter = 'latex';
            app.kVLabel.Text = ' kV ';
            app.kVLabel_2 = uilabel(app.InputDataPanel);
            app.kVLabel_2.Interpreter = 'latex';
            app.kVLabel_2.Text = ' kV ';
            app.Label = uilabel(app.InputDataPanel);
            app.Label.Interpreter = 'latex';
            app.Label.Text = '$Ω$';
            app.Label_2 = uilabel(app.InputDataPanel);
            app.Label_2.Interpreter = 'latex';
            app.Label_2.Text = '$Ω$';
            app.Label_3 = uilabel(app.InputDataPanel);
            app.Label_3.Interpreter = 'latex';
            app.Label_3.Text = '$Ω$';
            app.jLabel_7 = uilabel(app.InputDataPanel);
            app.jLabel_7.Interpreter = 'latex';
            app.jLabel_7.Text = '+ j';
            app.jLabel_8 = uilabel(app.InputDataPanel);
            app.jLabel_8.Interpreter = 'latex';
            app.jLabel_8.Text = '+ j';
            app.jLabel_9 = uilabel(app.InputDataPanel);
            app.jLabel_9.Interpreter = 'latex';
            app.jLabel_9.Text = '+ j';
            app.sLabel = uilabel(app.InputDataPanel);
            app.sLabel.Interpreter = 'latex';
            app.sLabel.Text = ' s ';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Sistem_Pentanahan_v3

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end